classdef transformador_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AjudaButton                  matlab.ui.control.Button
        CheckBoxGraficos             matlab.ui.control.CheckBox
        EnsaiodeTranformadoresLabel  matlab.ui.control.Label
        TypeButtonGroup              matlab.ui.container.ButtonGroup
        MonofsicoButton              matlab.ui.control.RadioButton
        TrifsicoButton               matlab.ui.control.RadioButton
        CalcularButton               matlab.ui.control.Button
        SNEditField                  matlab.ui.control.NumericEditField
        S_NKVALabel                  matlab.ui.control.Label
        VNATEditField                matlab.ui.control.NumericEditField
        V_NATKVLabel                 matlab.ui.control.Label
        VNBTEditField                matlab.ui.control.NumericEditField
        V_NBTVLabel                  matlab.ui.control.Label
        V0EditField                  matlab.ui.control.NumericEditField
        V_0VLabel                    matlab.ui.control.Label
        I0EditField                  matlab.ui.control.NumericEditField
        I_0ALabel                    matlab.ui.control.Label
        P0EditField                  matlab.ui.control.NumericEditField
        P_0WLabel                    matlab.ui.control.Label
        VccEditField                 matlab.ui.control.NumericEditField
        V_CCVLabel                   matlab.ui.control.Label
        IccEditField                 matlab.ui.control.NumericEditField
        I_ccALabel                   matlab.ui.control.Label
        PCCEditField                 matlab.ui.control.NumericEditField
        P_CCWLabel                   matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        IIFigura1Tab                 matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        Title_01                     matlab.ui.control.Label
        Title_02                     matlab.ui.control.Label
        Out_01                       matlab.ui.control.Label
        Out_02                       matlab.ui.control.Label
        Title_03                     matlab.ui.control.Label
        Out_03                       matlab.ui.control.Label
        IIFigura2Tab                 matlab.ui.container.Tab
        IIFigura3Tab                 matlab.ui.container.Tab
        IIIFigura1Tab                matlab.ui.container.Tab
        IIIFIgura2Tab                matlab.ui.container.Tab
        IIIFIgura3Tab                matlab.ui.container.Tab
        VIIITab                      matlab.ui.container.Tab
        SalvarCheckBox               matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.S_NKVALabel.Interpreter  = 'latex'
            app.V_NATKVLabel.Interpreter  = 'latex'
            app.V_NBTVLabel.Interpreter  = 'latex'
            app.V_0VLabel.Interpreter  = 'latex'
            app.I_0ALabel.Interpreter  = 'latex'
            app.P_0WLabel.Interpreter  = 'latex'
            app.V_CCVLabel.Interpreter  = 'latex'
            app.I_ccALabel.Interpreter  = 'latex'
            app.P_CCWLabel.Interpreter  = 'latex'
        end

        % Button pushed function: AjudaButton
        function AjudaButtonPushed(app, event)
            web("https://github.com/emanuel-alves/Trabalho_EELI16")
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            close all;
            switch app.TypeButtonGroup.SelectedObject.Text
                case 'Monofásico'
                    
                    tranformador_monofasico( ...
                        app.SNEditField.Value, ...
                        app.VNATEditField.Value, ...
                        app.VNBTEditField.Value, ...
                        app.V0EditField.Value, ...
                        app.I0EditField.Value, ...
                        app.P0EditField.Value, ...
                        app.VccEditField.Value, ...
                        app.IccEditField.Value, ...
                        app.PCCEditField.Value, ...
                        app.CheckBoxGraficos.Value, ...
                        app.SalvarCheckBox.Value)
                case 'Trifásico'
                    tranformador_trifasico( ...
                        app.SNEditField.Value, ...
                        app.VNATEditField.Value, ...
                        app.VNBTEditField.Value, ...
                        app.V0EditField.Value, ...
                        app.I0EditField.Value, ...
                        app.P0EditField.Value, ...
                        app.VccEditField.Value, ...
                        app.IccEditField.Value, ...
                        app.PCCEditField.Value, ...
                        app.CheckBoxGraficos.Value, ...
                        app.SalvarCheckBox.Value, ...
                        dir)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AjudaButton = uibutton(app.UIFigure, 'push');
            app.AjudaButton.ButtonPushedFcn = createCallbackFcn(app, @AjudaButtonPushed, true);
            app.AjudaButton.Text = 'Ajuda';
            app.CheckBoxGraficos = uicheckbox(app.UIFigure);
            app.CheckBoxGraficos.Text = 'Gerar Gráficos';
            app.CheckBoxGraficos.Value = true;
            app.EnsaiodeTranformadoresLabel = uilabel(app.UIFigure);
            app.EnsaiodeTranformadoresLabel.Text = 'Ensaio de Tranformadores';
            app.TypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeButtonGroup.Title = 'Tipo de transformador';
            app.MonofsicoButton = uiradiobutton(app.TypeButtonGroup);
            app.MonofsicoButton.Text = 'Monofásico';
            app.MonofsicoButton.Value = true;
            app.TrifsicoButton = uiradiobutton(app.TypeButtonGroup);
            app.TrifsicoButton.Text = 'Trifásico';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = {'Calcular'; ''};
            app.SNEditField = uieditfield(app.UIFigure, 'numeric');
            app.S_NKVALabel = uilabel(app.UIFigure);
            app.S_NKVALabel.Text = '$S_N \ [KVA] $';
            app.VNATEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_NATKVLabel = uilabel(app.UIFigure);
            app.V_NATKVLabel.Text = '$V_{NAT} \ [KV] $';
            app.VNBTEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_NBTVLabel = uilabel(app.UIFigure);
            app.V_NBTVLabel.Text = '$V_{NBT} \ [V] $';
            app.V0EditField = uieditfield(app.UIFigure, 'numeric');
            app.V_0VLabel = uilabel(app.UIFigure);
            app.V_0VLabel.Text = '$V_0 \ [V] $';
            app.I0EditField = uieditfield(app.UIFigure, 'numeric');
            app.I_0ALabel = uilabel(app.UIFigure);
            app.I_0ALabel.Text = '$I_0 \ [A] $';
            app.P0EditField = uieditfield(app.UIFigure, 'numeric');
            app.P_0WLabel = uilabel(app.UIFigure);
            app.P_0WLabel.Text = '$P_0 \ [W] $';
            app.VccEditField = uieditfield(app.UIFigure, 'numeric');
            app.V_CCVLabel = uilabel(app.UIFigure);
            app.V_CCVLabel.Text = '$V_{CC} \ [V] $';
            app.IccEditField = uieditfield(app.UIFigure, 'numeric');
            app.I_ccALabel = uilabel(app.UIFigure);
            app.I_ccALabel.Text = '$I_{cc } \ [A] $';
            app.PCCEditField = uieditfield(app.UIFigure, 'numeric');
            app.P_CCWLabel = uilabel(app.UIFigure);
            app.P_CCWLabel.Text = '$P_{CC} \ [W] $';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IIFigura1Tab = uitab(app.TabGroup);
            app.IIFigura1Tab.Title = 'II - Figura 1';
            app.GridLayout = uigridlayout(app.IIFigura1Tab);
            app.Title_01 = uilabel(app.GridLayout);
            app.Title_01.Text = 'S1:';
            app.Title_02 = uilabel(app.GridLayout);
            app.Title_02.Text = 'S2:';
            app.Out_01 = uilabel(app.GridLayout);
            app.Out_01.Text = '-';
            app.Out_02 = uilabel(app.GridLayout);
            app.Out_02.Text = '-';
            app.Title_03 = uilabel(app.GridLayout);
            app.Title_03.Text = 'S3:';
            app.Out_03 = uilabel(app.GridLayout);
            app.Out_03.Text = '-';
            app.IIFigura2Tab = uitab(app.TabGroup);
            app.IIFigura2Tab.Title = 'II - Figura 2';
            app.IIFigura3Tab = uitab(app.TabGroup);
            app.IIFigura3Tab.Title = 'II - Figura 3';
            app.IIIFigura1Tab = uitab(app.TabGroup);
            app.IIIFigura1Tab.Title = 'III - Figura 1';
            app.IIIFIgura2Tab = uitab(app.TabGroup);
            app.IIIFIgura2Tab.Title = 'III - FIgura 2';
            app.IIIFIgura3Tab = uitab(app.TabGroup);
            app.IIIFIgura3Tab.Title = 'III - FIgura 3';
            app.VIIITab = uitab(app.TabGroup);
            app.VIIITab.Title = 'VIII';
            app.SalvarCheckBox = uicheckbox(app.UIFigure);
            app.SalvarCheckBox.Text = 'Salvar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transformador_calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end